classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VelocidadenyEditField           matlab.ui.control.NumericEditField
        VelocidadenyEditFieldLabel      matlab.ui.control.Label
        VelocidadenyEditField_2         matlab.ui.control.NumericEditField
        VelocidadenyEditField_2Label    matlab.ui.control.Label
        VelocidadenxEditField_2         matlab.ui.control.NumericEditField
        VelocidadenxEditField_2Label    matlab.ui.control.Label
        VelocidadenxEditField           matlab.ui.control.NumericEditField
        VelocidadenxEditFieldLabel      matlab.ui.control.Label
        FuerzadelvientofwSlider         matlab.ui.control.Slider
        FuerzadelvientofwSliderLabel    matlab.ui.control.Label
        AlturaEditField_2               matlab.ui.control.NumericEditField
        AlturaEditField_2Label          matlab.ui.control.Label
        DistanciaEditField_2            matlab.ui.control.NumericEditField
        DistanciaEditField_2Label       matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel         matlab.ui.control.Label
        AlturaEditField                 matlab.ui.control.NumericEditField
        AlturaEditFieldLabel            matlab.ui.control.Label
        CambioentiempodtSlider          matlab.ui.control.Slider
        CambioentiempodtSliderLabel     matlab.ui.control.Label
        AlturadelvolcanSlider_2         matlab.ui.control.Slider
        AlturadelvolcanSlider_2Label    matlab.ui.control.Label
        MasadelProyectilSlider_2        matlab.ui.control.Slider
        MasadelProyectilSlider_2Label   matlab.ui.control.Label
        AngulodelanzamientoSlider       matlab.ui.control.Slider
        AngulodelanzamientoSliderLabel  matlab.ui.control.Label
        AreadelproyectilSlider_2        matlab.ui.control.Slider
        AreadelproyectilSlider_2Label   matlab.ui.control.Label
        VelocidaddelproyectilSlider     matlab.ui.control.Slider
        VelocidaddelproyectilSliderLabel  matlab.ui.control.Label
        CoeficientedearrastrecdSlider   matlab.ui.control.Slider
        CoeficientedearrastrecdLabel    matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        DesplazamientoMaxEditField_2    matlab.ui.control.NumericEditField
        DesplazamientoMaxEditField_2Label  matlab.ui.control.Label
        AlturaMaxEditField_2            matlab.ui.control.NumericEditField
        AlturaMaxEditField_2Label       matlab.ui.control.Label
        TiempoEditField_2               matlab.ui.control.NumericEditField
        TiempoEditField_2Label          matlab.ui.control.Label
        DesplazamientoMaxEditField      matlab.ui.control.NumericEditField
        DesplazamientoMaxEditFieldLabel  matlab.ui.control.Label
        ResultadosconfriccionLabel      matlab.ui.control.Label
        AlturaMaxEditField              matlab.ui.control.NumericEditField
        AlturaMaxEditFieldLabel         matlab.ui.control.Label
        ResultadossinfriccionLabel      matlab.ui.control.Label
        TiempoEditField                 matlab.ui.control.NumericEditField
        TiempoEditFieldLabel            matlab.ui.control.Label
        DatosgeneralesLabel             matlab.ui.control.Label
        DatosdelvolcanLabel             matlab.ui.control.Label
        DatosdelproyectilLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function GraficarButtonPushed(app, event)
     

        end

        % Value changed function: TiempoEditField_2
        function TiempoEditField_2ValueChanged(app, event)
            value = app.TiempoEditField_2.Value;
            
        end

        % Callback function
        function GraficarfriccionButtonPushed(app, event)
          
        end

        % Callback function
        function PruebaButtonPushed(app, event)
           
        end

        % Callback function
        function GraficarconfriccionButtonPushed(app, event)
           
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed2(app, event)
       
           cla(app.UIAxes)
        
        hold(app.UIAxes,'on')
        title(app.UIAxes,'Simulador de erupcion de volcan')
        xlabel(app.UIAxes,'Desplazamiento en el eje X (metros)')
        ylabel(app.UIAxes,'Desplazamiento en el eje Y (metros)')
            
            linea_1 = animatedline(app.UIAxes,'linewidth',2,'color','r')
            linea_2 = animatedline(app.UIAxes,'linewidth',2,'color','k')
            
            legend(app.UIAxes,'Proyectil sin Fricción','Proyectil con Fricción')
            

                %Movimiento sin Friccion
                %valores consola
                
            val1 = app.VelocidaddelproyectilSlider.Value
            val2 = app.AngulodelanzamientoSlider.Value
            val3 = app.AlturadelvolcanSlider_2.Value
            val4 = app.CambioentiempodtSlider.Value
            
            %constantes
            v0 = val1
            theta = (pi/180)*val2
            altura = val3
            dt = val4
            g = -9.81
            
            
            %Ecuaciones
            vel_x0 = v0 * cos(theta) 
            vel_y0 = v0 * sin(theta)
            t_max = (2 * vel_y0)/(-g)
            altura_max = (vel_y0^2)/(2 * -g)
            desplazamieto_max = (vel_x0 * vel_y0 * 2)/(-g)
            v_tiempo = 0:dt:t_max
            
            addpoints(linea_1,0,altura)
            
            v_loc_y(1) = altura
            
            tiempo = dt
            num = 1
            
            while v_loc_y(num) >= 0
                num = num + 1
                tiempo = tiempo + dt
                v_loc_x(num) = vel_x0 * tiempo;
                v_loc_y(num) = altura+ vel_y0*tiempo + (0.5*g*(tiempo^2));
                
                if(v_loc_y(num) < 0)
                   break
                   
                end
                app.VelocidadenxEditField.Value = vel_x0;
                app.VelocidadenyEditField.Value = vel_y0;
                app.DistanciaEditField.Value=(v_loc_x(num));
                app.AlturaEditField.Value=(v_loc_y(num));
                addpoints(linea_1,v_loc_x(num),v_loc_y(num))
                drawnow limitrate
                pause(0.1)
            end
            
             if app.AlturadelvolcanSlider_2.Value == 0
                 addpoints(linea_1,desplazamieto_max,0)
            
                 app.DesplazamientoMaxEditField.Value = desplazamieto_max;
                 app.TiempoEditField.Value = t_max
                 app.AlturaMaxEditField.Value = altura_max
             else
    
                 addpoints(linea_1, v_loc_x(end), 0)
                 app.DesplazamientoMaxEditField.Value = v_loc_x(end)
                 app.TiempoEditField.Value = tiempo
                 app.AlturaMaxEditField.Value = altura_max + altura
             end
         
          % Movimiento con Fricción
        %Valores de consola
        val1_1 = app.CambioentiempodtSlider.Value; 
        val2_1 = app.VelocidaddelproyectilSlider.Value; 
        val3_1 = app.AngulodelanzamientoSlider.Value; 
        val4_1 = app.AlturadelvolcanSlider_2.Value;
        val5_1 = app.MasadelProyectilSlider_2.Value; 
        val6_1 = app.CoeficientedearrastrecdSlider.Value;
        val7_1 = app.AreadelproyectilSlider_2.Value;
        val8_1 = app.FuerzadelvientofwSlider.Value;
                
        dt1 = val1_1
        v01 = val2_1
        theta1 = val3_1
        altura1 = val4_1
        masa1 = val5_1
        cd1 = val6_1
        area1 = val7_1
        fw = val8_1
                
        %constantes
        x = 0; 
        x1 = x;
        y = altura1
        y1 = y;
        g = -9.81; 
        v_loc_x1 = [x1];
        v_loc_y1 = [y1];
        tiempo_frx = 0;
        altura_frx = 0;
        p0 = 1.2;
        k = 1.380649 * 10^(-23);
        m = 4.837 * 10^(-26);
        t0 = 1250
        t_ambiente = 25;
        r0 = 10;
        num1 = 1;


        %Ecuaciones
        vel_x1 = v01*cos(deg2rad(theta1));
        vel_y1 = v01*sin(deg2rad(theta1));
        c = t0 - t_ambiente
        k_lo = log((1200 - t_ambiente) / c) / r0
        pz = p0 * exp(-( m * g * y1)/(k * t0))
        
        pw = p0 * exp(-( m * g * fw)/(k * t0))

        
        addpoints(linea_2,x1,y1)


        % Metodo Euler
        while v_loc_y1(num1) >= 0
            
            num1 = num1 + 1;


            tiempo_frx = tiempo_frx + dt1
    
            x_frx = -0.5 * cd1 * area1 * (pz - pw) * (vel_x1^2);
            acelercion_x = x_frx/masa1;
            vel_x1 = vel_x1 + acelercion_x *dt1;
            x1 = vel_x1*dt1 + 0.5*acelercion_x*(dt1^2) + x1;
            v_loc_x1 = [v_loc_x1, x1];
    
            y_frx = -0.5*cd1*area1* (pz - pw) *(vel_y1^2);
            aceleracion_y1 = (y_frx + (masa1 * g)) / masa1;
            vel_y1 = vel_y1 + aceleracion_y1 * dt1;
            y1 = vel_y1 *   dt1 + 0.5*aceleracion_y1*(dt1^2) + y1;
            v_loc_y1 = [v_loc_y1, y1];
            
            t0 = t0 - dt;
            
            pz = p0 * exp(-( m * g * y1)/(k * t0))
        
            pw = p0 * exp(-( m * g * fw)/(k * t0))
            
            if(y1 > 0)
                
                if (vel_y1 < 0)
                    
                app.DistanciaEditField_2.Value=(v_loc_x1(num1));
                app.VelocidadenxEditField_2.Value = vel_x1
                app.VelocidadenyEditField_2.Value = -vel_y1
                app.AlturaEditField_2.Value=(v_loc_y1(num1));
                desp_x = x1
                addpoints(linea_2,x1,y1)
                drawnow limitrate
                pause(0.1)
                
                else
                app.DistanciaEditField_2.Value=(v_loc_x1(num1));
                app.VelocidadenxEditField_2.Value = vel_x1
                app.VelocidadenyEditField_2.Value = vel_y1
                app.AlturaEditField_2.Value=(v_loc_y1(num1));
                desp_x = x1
                addpoints(linea_2,x1,y1)
                drawnow limitrate
                pause(0.1)
                end


            end
            if(altura_frx < y1)
                altura_frx = y1
            end
            
            
        end
        addpoints(linea_2,desp_x + (x1-desp_x)/2,0) 
        app.DesplazamientoMaxEditField_2.Value = desp_x + (x1-desp_x)/2;
        app.TiempoEditField_2.Value = (tiempo_frx + dt1/2)
        app.AlturaMaxEditField_2.Value = altura_frx


    hold(app.UIAxes,'off')  
        end

        % Value changed function: AlturadelvolcanSlider_2
        function AlturadelvolcanSlider_2ValueChanged(app, event)
            value = app.AlturadelvolcanSlider_2.Value;
            
        end

        % Value changed function: VelocidadenxEditField
        function VelocidadenxEditFieldValueChanged(app, event)
            value = app.VelocidadenxEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulador de erupcion de volcan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatosdelproyectilLabel = uilabel(app.UIFigure);
            app.DatosdelproyectilLabel.Text = 'Datos del proyectil';
            app.DatosdelvolcanLabel = uilabel(app.UIFigure);
            app.DatosdelvolcanLabel.Text = 'Datos del volcan';
            app.DatosgeneralesLabel = uilabel(app.UIFigure);
            app.DatosgeneralesLabel.Text = 'Datos generales';
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = 'Tiempo ';
            app.TiempoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultadossinfriccionLabel = uilabel(app.UIFigure);
            app.ResultadossinfriccionLabel.Text = 'Resultados sin friccion';
            app.AlturaMaxEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaMaxEditFieldLabel.Text = 'Altura Max';
            app.AlturaMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultadosconfriccionLabel = uilabel(app.UIFigure);
            app.ResultadosconfriccionLabel.Text = 'Resultados con friccion';
            app.DesplazamientoMaxEditFieldLabel = uilabel(app.UIFigure);
            app.DesplazamientoMaxEditFieldLabel.Text = 'Desplazamiento Max';
            app.DesplazamientoMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditField_2Label = uilabel(app.UIFigure);
            app.TiempoEditField_2Label.Text = 'Tiempo ';
            app.TiempoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditField_2.ValueChangedFcn = createCallbackFcn(app, @TiempoEditField_2ValueChanged, true);
            app.AlturaMaxEditField_2Label = uilabel(app.UIFigure);
            app.AlturaMaxEditField_2Label.Text = 'Altura Max';
            app.AlturaMaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DesplazamientoMaxEditField_2Label = uilabel(app.UIFigure);
            app.DesplazamientoMaxEditField_2Label.Text = 'Desplazamiento Max';
            app.DesplazamientoMaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed2, true);
            app.GraficarButton.Text = 'Graficar';
            app.CoeficientedearrastrecdLabel = uilabel(app.UIFigure);
            app.CoeficientedearrastrecdLabel.Text = 'Coeficiente de arrastre (cd)';
            app.CoeficientedearrastrecdSlider = uislider(app.UIFigure);
            app.CoeficientedearrastrecdSlider.Limits = [0.1 1];
            app.CoeficientedearrastrecdSlider.Value = 1;
            app.VelocidaddelproyectilSliderLabel = uilabel(app.UIFigure);
            app.VelocidaddelproyectilSliderLabel.Text = 'Velocidad del proyectil';
            app.VelocidaddelproyectilSlider = uislider(app.UIFigure);
            app.VelocidaddelproyectilSlider.Limits = [200 300];
            app.VelocidaddelproyectilSlider.Value = 300;
            app.AreadelproyectilSlider_2Label = uilabel(app.UIFigure);
            app.AreadelproyectilSlider_2Label.Text = 'Area del proyectil';
            app.AreadelproyectilSlider_2 = uislider(app.UIFigure);
            app.AreadelproyectilSlider_2.Limits = [1 10];
            app.AreadelproyectilSlider_2.Value = 5;
            app.AngulodelanzamientoSliderLabel = uilabel(app.UIFigure);
            app.AngulodelanzamientoSliderLabel.Text = 'Angulo de lanzamiento';
            app.AngulodelanzamientoSlider = uislider(app.UIFigure);
            app.AngulodelanzamientoSlider.Limits = [10 70];
            app.AngulodelanzamientoSlider.Value = 30;
            app.MasadelProyectilSlider_2Label = uilabel(app.UIFigure);
            app.MasadelProyectilSlider_2Label.Text = 'Masa del Proyectil';
            app.MasadelProyectilSlider_2 = uislider(app.UIFigure);
            app.MasadelProyectilSlider_2.Limits = [1000 5000];
            app.MasadelProyectilSlider_2.Value = 5000;
            app.AlturadelvolcanSlider_2Label = uilabel(app.UIFigure);
            app.AlturadelvolcanSlider_2Label.Text = 'Altura del volcan';
            app.AlturadelvolcanSlider_2 = uislider(app.UIFigure);
            app.AlturadelvolcanSlider_2.Limits = [1500 6000];
            app.AlturadelvolcanSlider_2.ValueChangedFcn = createCallbackFcn(app, @AlturadelvolcanSlider_2ValueChanged, true);
            app.AlturadelvolcanSlider_2.Value = 3000;
            app.CambioentiempodtSliderLabel = uilabel(app.UIFigure);
            app.CambioentiempodtSliderLabel.Text = 'Cambio en tiempo (dt)';
            app.CambioentiempodtSlider = uislider(app.UIFigure);
            app.CambioentiempodtSlider.Limits = [0.1 0.5];
            app.CambioentiempodtSlider.Value = 0.5;
            app.AlturaEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaEditField_2Label = uilabel(app.UIFigure);
            app.DistanciaEditField_2Label.Text = 'Distancia';
            app.DistanciaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AlturaEditField_2Label = uilabel(app.UIFigure);
            app.AlturaEditField_2Label.Text = 'Altura';
            app.AlturaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FuerzadelvientofwSliderLabel = uilabel(app.UIFigure);
            app.FuerzadelvientofwSliderLabel.Text = 'Fuerza del viento (fw)';
            app.FuerzadelvientofwSlider = uislider(app.UIFigure);
            app.FuerzadelvientofwSlider.Limits = [0 15];
            app.FuerzadelvientofwSlider.Value = 0.5;
            app.VelocidadenxEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadenxEditFieldLabel.Text = 'Velocidad en x';
            app.VelocidadenxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadenxEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidadenxEditFieldValueChanged, true);
            app.VelocidadenxEditField_2Label = uilabel(app.UIFigure);
            app.VelocidadenxEditField_2Label.Text = 'Velocidad en x';
            app.VelocidadenxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadenyEditField_2Label = uilabel(app.UIFigure);
            app.VelocidadenyEditField_2Label.Text = 'Velocidad en y';
            app.VelocidadenyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadenyEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadenyEditFieldLabel.Text = 'Velocidad en y';
            app.VelocidadenyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end